classdef ee415_term_project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SelectyourinputimageButtonGroup  matlab.ui.container.ButtonGroup
        SquareButton             matlab.ui.control.RadioButton
        SheppLoganButton         matlab.ui.control.RadioButton
        LenaButton               matlab.ui.control.RadioButton
        SelectyourfiltertypeButtonGroup  matlab.ui.container.ButtonGroup
        RampButton               matlab.ui.control.RadioButton
        SinusoidalButton         matlab.ui.control.RadioButton
        GaussianButton           matlab.ui.control.RadioButton
        WithoutfilterButton      matlab.ui.control.RadioButton
        ProjectionButton         matlab.ui.control.Button
        BeamnumberSliderLabel    matlab.ui.control.Label
        BeamnumberSlider         matlab.ui.control.Slider
        StepsizeSpinnerLabel     matlab.ui.control.Label
        StepsizeSpinner          matlab.ui.control.Spinner
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        BackProjectionButton     matlab.ui.control.Button
        UIAxes3                  matlab.ui.control.UIAxes
        UIAxes4                  matlab.ui.control.UIAxes
        AngleNumberSpinnerLabel  matlab.ui.control.Label
        AngleNumberSpinner       matlab.ui.control.Spinner
    end

    
    properties (Access = public)
        projection_public
        projection_filtered_public
        t_public
        theta_public
        M_public
    end


    methods (Access = private)

        % Selection changed function: SelectyourinputimageButtonGroup
        function SelectyourinputimageButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectyourinputimageButtonGroup.SelectedObject;
            
            if app.SquareButton.Value
                matdata=load('square.mat');
                image=matdata.square;
                [M M]=size(image);
                app.M_public=M;
                gray_original=mat2gray(image);
                imshow(gray_original,'Parent',app.UIAxes);
            end
            
            if app.SheppLoganButton.Value
                matdata=load('SheppLogan.mat');
                image=matdata.SheppLogan;
                [M M]=size(image);
                app.M_public=M;
                gray_original=mat2gray(image);
                imshow(gray_original,'Parent',app.UIAxes);
            end
            
            if app.LenaButton.Value
                matdata=load('lena.mat');
                image=matdata.lena;
                [M M]=size(image);
                app.M_public=M;
                gray_original=mat2gray(image);
                imshow(gray_original,'Parent',app.UIAxes);
            end
            
        end

        % Selection changed function: SelectyourfiltertypeButtonGroup
        function SelectyourfiltertypeButtonGroupSelectionChanged(app, event)
            
            selectedButton = app.SelectyourfiltertypeButtonGroup.SelectedObject;
            
            value = app.BeamnumberSlider.Value;
            M=app.M_public;
            t_num=value;
            t=linspace(-M*sqrt(2)/2,M*sqrt(2)/2,t_num);
            app.t_public=t;
            step=app.StepsizeSpinner.Value;
            theta=0:step:(180-step);
            app.theta_public=theta;
            
            
            projection=app.projection_public;
            t=app.t_public;
            theta=app.theta_public;
            
            if app.RampButton.Value
                high_pass=triang(length(t))';
                plot(high_pass,'Parent',app.UIAxes3);
            end
            if app.GaussianButton.Value
                ind=linspace(0,10,length(t));
                high_pass=gaussmf(ind,[2 5])';
                plot(high_pass,'Parent',app.UIAxes3);
            end
            if app.SinusoidalButton.Value
                ind=linspace(0,pi,length(t));
                high_pass=sin(ind);
                plot(high_pass,'Parent',app.UIAxes3);
            end
            if app.WithoutfilterButton.Value
                plot(ones(length(t)),'color',[0 0 1],'Parent',app.UIAxes3);
            end
            
            
        end

        % Button pushed function: ProjectionButton
        function ProjectionButtonPushed(app, event)
            if app.SquareButton.Value
                matdata=load('square.mat');
                image=matdata.square;
                [M M]=size(image);
                app.M_public=M;
                x_val=-M/2:M/2;
                y_val=-M/2:M/2;
            end
            if app.SheppLoganButton.Value
                matdata=load('SheppLogan.mat');
                image=matdata.SheppLogan;
                [M M]=size(image);
                x_val=-M/2:M/2;
                y_val=-M/2:M/2
            end
            
            if app.LenaButton.Value
                matdata=load('lena.mat');
                image=matdata.lena;
                [M M]=size(image);
                x_val=-M/2:M/2;
                y_val=-M/2:M/2;
            end
            value = app.BeamnumberSlider.Value;
            t_num=value;
            t=linspace(-M*sqrt(2)/2,M*sqrt(2)/2,t_num);
            app.t_public=t;
            step=app.StepsizeSpinner.Value;
            theta=0:step:(180-step);
            app.theta_public=theta;
            projection=[];
            for i=1:length(theta)
                projection_i_j=[];
                intersection_points_y=[];
                intersection_points_x=[];
                for j=1:length(t)
                    intersection_points_y=(t(j)-x_val*cos(theta(i)/180*pi))/sin(theta(i)/180*pi);
                    points_y=[intersection_points_y; y_val];
                    points_y=wthresh(points_y,'h',1e-10);
                    intersection_points_x=(t(j)-y_val*sin(theta(i)/180*pi))/cos(theta(i)/180*pi);
                    points_x=[x_val; intersection_points_x];
                    points_x=wthresh(points_x,'h',1e-10);
                    points=[points_x points_y];
                    relevant_points=[];
                    for k=1:size(points,2)
                        if(round(max(points(:,k)),8)<= round(M/2,8) && round(min(points(:,k)),8)>=round(-M/2,8))
                            relevant_points=[relevant_points points(:,k)];
                        end
                    end
                    relevant_points=rmmissing(relevant_points,2);
                    
                    %sorting relevant points
                    relevant_points=unique(round(relevant_points',8),'rows');
                    dist=[];
                    mid_points=[];
                    for m=1:(size(relevant_points,1)-1)
                        dist=[dist; sqrt(sum((relevant_points(m,:)-relevant_points(m+1,:)).*(relevant_points(m,:)-relevant_points(m+1,:))))];
                        mid_points=[mid_points; relevant_points(m,:)/2+relevant_points(m+1,:)/2];
                    end
                    
                    if ~isempty(mid_points)
                        rowdata=M/2-floor(mid_points(:,1));
                        columndata=M/2+ceil(mid_points(:,2));
                        address=[rowdata columndata];
                    end
                    
                    if isempty(mid_points)
                        projection_i_j=[projection_i_j 0];
                    else
                        r=1;
                        add=0;
                        for p=1:length(dist)
                            add=add+image(address(r,1),address(r,2))*dist(p);
                            r=r+1;
                        end
                        projection_i_j=[projection_i_j add];
                    end
                end
                projection=[projection; projection_i_j];
            end
            imshow(projection,'Parent',app.UIAxes_2);
            app.projection_public=projection;
            
            
        end

        % Button pushed function: BackProjectionButton
        function BackProjectionButtonPushed(app, event)
            M=app.M_public;
            projection=app.projection_public;
            value = app.BeamnumberSlider.Value;
            M=app.M_public;
            t_num=value;
            t=linspace(-M*sqrt(2)/2,M*sqrt(2)/2,t_num);
            step=app.StepsizeSpinner.Value;
            theta=0:step:(180-step);
            
            if app.RampButton.Value
                high_pass=triang(length(t))';
                plot(high_pass,'Parent',app.UIAxes3);
                high_pass=repmat(high_pass,length(theta),1);
                %%passing to frequency domain, multiplication, and then back to spatial
                %%domain
                fourier=fft(projection');
                fourier=fourier';
                projection_freqdom=fourier.*high_pass;
                projection_filtered=ifft(projection_freqdom');
                projection_filtered=projection_filtered';
                app.projection_filtered_public=projection_filtered;
            end
            if app.GaussianButton.Value
                ind=linspace(0,10,length(t));
                high_pass=gaussmf(ind,[2 5]);
                plot(high_pass,'Parent',app.UIAxes3);
                high_pass=repmat(high_pass,length(theta),1);
                %%passing to frequency domain, multiplication, and then back to spatial
                %%domain
                fourier=fft(projection');
                fourier=fourier';
                projection_freqdom=fourier.*high_pass;
                projection_filtered=ifft(projection_freqdom');
                projection_filtered=projection_filtered';
                app.projection_filtered_public=projection_filtered;
            end
            if app.SinusoidalButton.Value
                ind=linspace(0,pi,length(t));
                high_pass=sin(ind);
                plot(high_pass,'Parent',app.UIAxes3);
                high_pass=repmat(high_pass,length(theta),1);
                %%passing to frequency domain, multiplication, and then back to spatial
                %%domain
                fourier=fft(projection');
                fourier=fourier';
                projection_freqdom=fourier.*high_pass;
                projection_filtered=ifft(projection_freqdom');
                projection_filtered=projection_filtered';
                app.projection_filtered_public=projection_filtered;
            end
            if app.WithoutfilterButton.Value
                projection_filtered=projection;
                filter=ones(length(t));
                plot(filter,'Parent',app.UIAxes3);
                app.projection_filtered_public=projection_filtered;
            end        
            x_val=-M/2:M/2;
            y_val=-M/2:M/2;
            back_projection=zeros(M,M);
            for i=1:length(theta)
                intersection_points_y=[];
                intersection_points_x=[];
                for j=1:length(t)
                    intersection_points_y=(t(j)-x_val*cos(theta(i)/180*pi))/sin(theta(i)/180*pi);
                    points_y=[intersection_points_y; y_val];
                    points_y=wthresh(points_y,'h',1e-10);
                    intersection_points_x=(t(j)-y_val*sin(theta(i)/180*pi))/cos(theta(i)/180*pi);
                    points_x=[x_val; intersection_points_x];
                    points_x=wthresh(points_x,'h',1e-10);
                    points=[points_x points_y];
                    relevant_points=[];
                    for k=1:size(points,2)
                        if(round(max(points(:,k)),8)<= round(M/2,8) && round(min(points(:,k)),8)>=round(-M/2,8))
                            relevant_points=[relevant_points points(:,k)];
                        end
                    end
                    relevant_points=rmmissing(relevant_points,2);
                    %sorting relevant points
                    relevant_points=unique(round(relevant_points',8),'rows');
                    dist=[];
                    mid_points=[];
                    for m=1:(size(relevant_points,1)-1)
                        dist=[dist; sqrt(sum((relevant_points(m,:)-relevant_points(m+1,:)).*(relevant_points(m,:)-relevant_points(m+1,:))))];
                        mid_points=[mid_points; relevant_points(m,:)/2+relevant_points(m+1,:)/2];
                    end
                    
                    if ~isempty(mid_points)
                        rowdata=M/2-floor(mid_points(:,1));
                        columndata=M/2+ceil(mid_points(:,2));
                        address=[rowdata columndata];
                    end
                    
                    if ~isempty(mid_points)
                        u=size(mid_points,1);
                        for m=1:(u-1)
                            back_projection(rowdata(m),columndata(m))=projection_filtered(i,j)*dist(m,1)+back_projection(rowdata(m),columndata(m));
                        end
                    end
                end
            end
            grayrecon=mat2gray(abs(back_projection));
            imshow(grayrecon,'Parent',app.UIAxes2);
        end

        % Value changed function: AngleNumberSpinner
        function AngleNumberSpinnerValueChanged(app, event)
            value = app.AngleNumberSpinner.Value;
            value1 = app.BeamnumberSlider.Value;
            M=app.M_public;
            t_num=value1;
            t=linspace(-M*sqrt(2)/2,M*sqrt(2)/2,t_num);
            projection=app.projection_public;
            plot(t,projection(value,:),'Parent',app.UIAxes4);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SelectyourinputimageButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectyourinputimageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectyourinputimageButtonGroupSelectionChanged, true);
            app.SelectyourinputimageButtonGroup.Title = 'Select your input image';
            app.SquareButton = uiradiobutton(app.SelectyourinputimageButtonGroup);
            app.SquareButton.Text = 'Square';
            app.SquareButton.Value = true;
            app.SheppLoganButton = uiradiobutton(app.SelectyourinputimageButtonGroup);
            app.SheppLoganButton.Text = 'Shepp Logan';
            app.LenaButton = uiradiobutton(app.SelectyourinputimageButtonGroup);
            app.LenaButton.Text = 'Lena';
            app.SelectyourfiltertypeButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectyourfiltertypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectyourfiltertypeButtonGroupSelectionChanged, true);
            app.SelectyourfiltertypeButtonGroup.Title = 'Select your filter type';
            app.RampButton = uiradiobutton(app.SelectyourfiltertypeButtonGroup);
            app.RampButton.Text = 'Ramp';
            app.RampButton.Value = true;
            app.SinusoidalButton = uiradiobutton(app.SelectyourfiltertypeButtonGroup);
            app.SinusoidalButton.Text = 'Sinusoidal';
            app.GaussianButton = uiradiobutton(app.SelectyourfiltertypeButtonGroup);
            app.GaussianButton.Text = 'Gaussian';
            app.WithoutfilterButton = uiradiobutton(app.SelectyourfiltertypeButtonGroup);
            app.WithoutfilterButton.Text = 'Without filter';
            app.ProjectionButton = uibutton(app.UIFigure, 'push');
            app.ProjectionButton.ButtonPushedFcn = createCallbackFcn(app, @ProjectionButtonPushed, true);
            app.ProjectionButton.Text = 'Projection';
            app.BeamnumberSliderLabel = uilabel(app.UIFigure);
            app.BeamnumberSliderLabel.Text = 'Beam number';
            app.BeamnumberSlider = uislider(app.UIFigure);
            app.BeamnumberSlider.Limits = [0 300];
            app.BeamnumberSlider.Value = 1;
            app.StepsizeSpinnerLabel = uilabel(app.UIFigure);
            app.StepsizeSpinnerLabel.Text = 'Step size';
            app.StepsizeSpinner = uispinner(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Projection Image (Radon Transform)')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Reconstructed Image')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.BackProjectionButton = uibutton(app.UIFigure, 'push');
            app.BackProjectionButton.ButtonPushedFcn = createCallbackFcn(app, @BackProjectionButtonPushed, true);
            app.BackProjectionButton.Text = 'Back Projection';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'The Filter You Chose')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Projection function at a specific angle')
            xlabel(app.UIAxes4, 't axis')
            app.AngleNumberSpinnerLabel = uilabel(app.UIFigure);
            app.AngleNumberSpinnerLabel.Text = 'Angle Number';
            app.AngleNumberSpinner = uispinner(app.UIFigure);
            app.AngleNumberSpinner.ValueChangedFcn = createCallbackFcn(app, @AngleNumberSpinnerValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = ee415_term_project

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end